classdef AppScope < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        UIAxes                 matlab.ui.control.UIAxes
        StartButton            matlab.ui.control.Button
        LogTextArea            matlab.ui.control.TextArea
        CountLabel             matlab.ui.control.Label
        CountEditField         matlab.ui.control.NumericEditField
        TriggerLampLabel       matlab.ui.control.Label
        TriggerLamp            matlab.ui.control.Lamp
        LeftEditFieldLabel     matlab.ui.control.Label
        LeftEditField          matlab.ui.control.NumericEditField
        RightEditFieldLabel    matlab.ui.control.Label
        RightEditField         matlab.ui.control.NumericEditField
        AverageEditFieldLabel  matlab.ui.control.Label
        AverageEditField       matlab.ui.control.NumericEditField
        ChannelDropDownLabel   matlab.ui.control.Label
        ChannelDropDown        matlab.ui.control.DropDown
        SaveDataButton         matlab.ui.control.Button
        TagEditFieldLabel      matlab.ui.control.Label
        TagEditField           matlab.ui.control.NumericEditField
        SelectDataFileButton   matlab.ui.control.Button
        SelelctTagFileButton   matlab.ui.control.Button
    end

    properties (Access = public)
        % The timer instance for trigger state checking.
        % The code is written according to https://www.mathworks.com/matlabcentral/answers/354713-can-the-matlab-app-designer-support-the-timer
        % The definition may not be moved to elsewhere.
        trig_timer = timer('StartDelay', 0.1, 'Period', 0.01, 'TasksToExecute', Inf, ...
            'ExecutionMode', 'fixedSpacing','BusyMode','drop');
    end
    properties (Access = private)
        scope % The scope object
        fid_data = -1; % File ID of the file to write data in
        fid_tag = -1;% File ID of the file containing the tag of each set of data
        tag = -1;% The tag of each set of data
    end
    
    methods (Access = private)
        
        % Initialize the scope object, specialized for Tektronix TDS1001B.
        function initScope(app)
            try
                % Find a VISA-USB object.
                app.scope = instrfind('Type', 'visa-usb', 'RsrcName', 'USB0::0x0699::0x0362::C103870::0::INSTR', 'Tag', '');
                
                % Create the VISA-USB object if it does not exist
                % otherwise use the object that was found.
                if isempty(app.scope)
                    app.scope = visa('NI', 'USB0::0x0699::0x0362::C103870::0::INSTR');
                else
                    fclose(app.scope);
                    app.scope = app.scope(1);
                end
                
                % Set the software (Matlab) input buffer so that the waveform data can be read in one time.
                set(app.scope, 'InputBufferSize', 20000);
                
                % Connect to instrument object.
                fopen(app.scope);
                
                % Update the log.
                app.LogTextArea.Value = {'Device Connected'};
            catch exception
                option=uiconfirm(app.UIFigure,exception.message,'Cannot Connect to the Device',...
                    'Icon','error','Options',{'Retry','Close'});
                if strcmp(option,'Retry')
                    app.initScope;
                else
                    app.UIFigureCloseRequest;
                end
            end
        end
        
        %Initialize the timer
        function initTimer(app)
            app.trig_timer.TimerFcn = @(~, ~) CheckTrigger(app,app.scope);
            app.trig_timer.Name = 'trig_timer';
        end
        
        function initApp(app)
            fid_data_path=fopen('DataFilePath.txt');
            if fid_data_path < 3
                app.LogTextArea = [app.LogTextArea;'Data file path is not set. Cannot save data.'];
            else
                data_path = fgetl(fid_data_path);
                app.LogTextArea = [app.LogTextArea; strcat('The data file path is set as',data_path)];
                app.fid_data = fopen(data_path);
                if app.fid_data < 3
                    app.LogTextArea = [app.LogTextArea; 'Cannot open the data file.'];
                end
            end
            
            
            app.fid_tag=fopen('TagFilePath.txt');
            if app.fid_tag < 3
                app.LogTextArea = [app.LogTextArea; ' Tag file path is not set. Tag is set to -1.'];
                app.TagEditField.Value = app.tag;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.initTimer;
            app.initScope;
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            stop(app.trig_timer);
            app.StartButton.Text = 'Start';
            result=uiconfirm(app.UIFigure,'Close app?','Confirm Close','Options',{'Confirm','Cancel'});
            if strcmp(result,'Confirm')
                delete(app.trig_timer)
                fclose(app.scope);
                delete(app)
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if strcmp(app.StartButton.Text,'Start')
                start(app.trig_timer);
                app.StartButton.Text = 'Stop';
            else
                stop(app.trig_timer);
                app.StartButton.Text = 'Start';
            end
        end

        % Button pushed function: SelectDataFileButton
        function SelectDataFileButtonPushed(app, event)
            uiopen()
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'T (s)')
            ylabel(app.UIAxes, 'U(V)')
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.LogTextArea = uitextarea(app.UIFigure);
            app.CountLabel = uilabel(app.UIFigure);
            app.CountLabel.Text = 'Count';
            app.CountEditField = uieditfield(app.UIFigure, 'numeric');
            app.CountEditField.Editable = 'off';
            app.TriggerLampLabel = uilabel(app.UIFigure);
            app.TriggerLampLabel.Text = 'Trigger';
            app.TriggerLamp = uilamp(app.UIFigure);
            app.LeftEditFieldLabel = uilabel(app.UIFigure);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftEditField = uieditfield(app.UIFigure, 'numeric');
            app.RightEditFieldLabel = uilabel(app.UIFigure);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightEditField = uieditfield(app.UIFigure, 'numeric');
            app.AverageEditFieldLabel = uilabel(app.UIFigure);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.UIFigure, 'numeric');
            app.ChannelDropDownLabel = uilabel(app.UIFigure);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.UIFigure);
            app.ChannelDropDown.Items = {'CH1', 'CH2'};
            app.ChannelDropDown.Value = 'CH1';
            app.SaveDataButton = uibutton(app.UIFigure, 'push');
            app.SaveDataButton.Text = 'Save Data';
            app.TagEditFieldLabel = uilabel(app.UIFigure);
            app.TagEditFieldLabel.Text = 'Tag';
            app.TagEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectDataFileButton = uibutton(app.UIFigure, 'push');
            app.SelectDataFileButton.ButtonPushedFcn = createCallbackFcn(app, @SelectDataFileButtonPushed, true);
            app.SelectDataFileButton.Text = 'Select Data File';
            app.SelelctTagFileButton = uibutton(app.UIFigure, 'push');
            app.SelelctTagFileButton.Text = 'Selelct Tag File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AppScope

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end